classdef Interfaz_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Cargar                          matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        AudioMixerLabel                 matlab.ui.control.Label
        Play                            matlab.ui.control.Button
        Pause                           matlab.ui.control.Button
        Resume                          matlab.ui.control.Button
        Stop                            matlab.ui.control.Button
        FiltroDropDownLabel             matlab.ui.control.Label
        FiltroDropDown                  matlab.ui.control.DropDown
        FiltroDropDown_2Label           matlab.ui.control.Label
        FiltroDropDown_2                matlab.ui.control.DropDown
        Canal1Label                     matlab.ui.control.Label
        Canal2Label                     matlab.ui.control.Label
        EfectoDropDownLabel             matlab.ui.control.Label
        EfectoDropDown                  matlab.ui.control.DropDown
        EfectoDropDown_2Label           matlab.ui.control.Label
        EfectoDropDown_2                matlab.ui.control.DropDown
        SwitchEfecto1                   matlab.ui.control.Switch
        SwitchEfecto2                   matlab.ui.control.Switch
        GananciaSpinnerLabel            matlab.ui.control.Label
        Ganancia1                       matlab.ui.control.Spinner
        NuevoarchivoEditFieldLabel      matlab.ui.control.Label
        NuevoarchivoEditField           matlab.ui.control.EditField
        GenerarButton                   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        GenerarButton_2                 matlab.ui.control.Button
        GuardarVariablesEditFieldLabel  matlab.ui.control.Label
        GuardarVariablesEditField       matlab.ui.control.EditField
        matLabel                        matlab.ui.control.Label
        wavLabel                        matlab.ui.control.Label
        GenerarSealdelFiltroButton      matlab.ui.control.Button
        GenerarSealdeEntradaButton      matlab.ui.control.Button
        GenerarSealdeSalidaButton       matlab.ui.control.Button
        AplicarCambios_1                matlab.ui.control.Button
        AplicarCambios_2                matlab.ui.control.Button
        SwitchFiltro1                   matlab.ui.control.Switch
        SwitchFiltro2                   matlab.ui.control.Switch
        FrecuenciadecorteSpinnerLabel   matlab.ui.control.Label
        Frecuenciadecorte1              matlab.ui.control.Spinner
        GananciaSpinner_2Label          matlab.ui.control.Label
        Ganancia2                       matlab.ui.control.Spinner
        FrecuenciadecorteSpinner_2Label  matlab.ui.control.Label
        Frecuenciadecorte2              matlab.ui.control.Spinner
        AlfaLabel                       matlab.ui.control.Label
        Alfa                            matlab.ui.control.Spinner
        DelaySliderLabel                matlab.ui.control.Label
        Delay                           matlab.ui.control.Slider
    end

    
    properties (Access = public)
        archivo     % Description
        player
        x_edit;
        x_edit1;
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: Cargar
        function CargarButtonPushed(app, event)
            [file,~] = uigetfile({'*.wav;*.mp3', 'Audio Files'});
            if isequal(file,0)
                app.EditField.BackgroundColor = 'red';
            else
                app.EditField.BackgroundColor = 'white';
                app.EditField.Value = file;
                app.archivo = file;
                [audio,Fs] = audioread(app.archivo);
                app.player = audioplayer(audio,Fs);
            end
        end

        % Button pushed function: Play
        function PlayButtonPushed(app, event)
            play(app.player);
        end

        % Button pushed function: Pause
        function PauseButtonPushed(app, event)
            pause(app.player);
        end

        % Button pushed function: Stop
        function StopButtonPushed(app, event)
            stop(app.player);
        end

        % Button pushed function: Resume
        function ResumeButtonPushed(app, event)
            resume(app.player);
        end

        % Button pushed function: GenerarSealdeEntradaButton
        function GenerarSealdeEntradaButtonPushed(app, event)
            [x,~] = audioread(app.archivo);
            t1 = (0:(1/(length(x)-1)):1);
            plot(app.UIAxes_2, t1,x);
        end

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
            [x_new,Fs_new] = audioread(app.archivo);
            Nuevo_archivo = app.NuevoarchivoEditField.Value+".wav";
            audiowrite(Nuevo_archivo, x_new, Fs_new);
        end

        % Button pushed function: AplicarCambios_1
        function AplicarCambios_1Pushed(app, event)
    
            val_efe1 = app.SwitchEfecto1.Value;
            efe_sel = app.EfectoDropDown.Value;
            
            [x,Fs] = audioread(app.archivo);

                
            if (strcmp(efe_sel, 'Eco')  && strcmp(val_efe1, 'On'))
                    alfa = 0.5;
                    D2 = app.Delay.Value;
                    app.x_edit1 = zeros(size(app.x_edit));
                    x2_n_D = 0;
                    y2_n_D = 0;
                    j = 0;
                    %delay2 = j-D2;
                    N = length(app.x_edit);
                    for j = 1:N
                        app.x_edit1(j) = app.x_edit(j) + (1-alfa).*x2_n_D + alfa.*y2_n_D;
                        if (j-11025 > 0)
                            x2_n_D = app.x_edit(j-D2);
                            y2_n_D = app.x_edit1(j-D2);
                        end
                    end
                    
            elseif (strcmp(efe_sel, 'Vibrato')  && strcmp(val_efe1, 'On'))      
                Modfreq=10;
                Width=0.0008;
                Delay = Width;  %app.Delay.Value % basic delay of input sample in sec
                DELAY=round(Delay*Fs); % basic delay in # samples
                WIDTH=round(Width*Fs); % modulation width in # samples

                MODFREQ = Modfreq/Fs; % modulation frequency in # samples
                LEN=length(app.x_edit); % # of samples in WAV-file
                L=2+DELAY+WIDTH*2; % length of the entire delay
                Delayline=zeros(L,1); % memory allocation for delay
                app.x_edit1=zeros(size(app.x_edit)); % memory allocation for output vector
                for n=1:(LEN-1)
                    M=MODFREQ;
                    MOD=sin(M*2*pi*n);
                    TAP=1+DELAY+WIDTH*MOD;
                    i=floor(TAP);
                    frac=TAP-i;
                    Delayline=[app.x_edit(n);Delayline(1:L-1)];
                        %---Linear Interpolation-----------------------------
                    app.x_edit1(n,1)=Delayline(i+1)*frac+Delayline(i)*(1-frac);

                end
                
            elseif (strcmp(efe_sel, 'Flanger')  && strcmp(val_efe1, 'On'))  
                a=2;
                delay=10e-3;
                D=ceil(delay*Fs);
                length1=length(app.x_edit);
                app.x_edit1=zeros(size(app.x_edit));


                for i=(1:length1)
                    if i<D+1
                        app.x_edit1(i)=app.x_edit(i);
                    else
                        delay(i)=abs(round(D*cos(2*pi*i/((length1-D-1)))));
                        app.x_edit1(i)=app.x_edit(i)+a*app.x_edit(i-delay(i));
                    end 
                end
            
            elseif (strcmp(efe_sel, 'Distorsión')  && strcmp(val_efe1, 'On'))
                
                gain = 5;
                mix = 2;
                q=app.x_edit*gain/max(abs(app.x_edit));
                z=sign(-q).*(1-exp(sign(-q).*q));
                app.x_edit1=mix*z*max(abs(app.x_edit))/max(abs(z))+(1-mix)*app.x_edit;
                app.x_edit1=app.x_edit1.*max(abs(app.x_edit))./max(abs(app.x_edit1));
                
            elseif (strcmp(efe_sel, 'Reverberación')  && strcmp(val_efe1, 'On'))
                mic=[19 18 1.6];
                n=12;
                r=0.3;
                rm=[20 19 21];
                src=[5 2 1];

                nn=-n:1:n;                            % Index for the sequence
                rms=nn+0.5-0.5*(-1).^nn;              % Part of equations 2,3,& 4
                srcs=(-1).^(nn);                      % part of equations 2,3,& 4
                xi=srcs*src(1)+rms*rm(1)-mic(1);      % Equation 2 
                yj=srcs*src(2)+rms*rm(2)-mic(2);      % Equation 3 
                zk=srcs*src(3)+rms*rm(3)-mic(3);      % Equation 4 
                [i,j,k]=meshgrid(xi,yj,zk);           % convert vectors to 3D matrices
                d=sqrt(i.^2+j.^2+k.^2);               % Equation 5
                time=round(Fs*d/343)+1;               % Similar to Equation 6
              
                [e,f,g]=meshgrid(nn, nn, nn);         % convert vectors to 3D matrices
                c=r.^(abs(e)+abs(f)+abs(g));          % Equation 9
                e=c./d;                               % Equivalent to Equation 10
                h=full(sparse(time(:),1,e(:)));       % Equivalent to equation 11
                h=h/max(abs(h));
                Ly=length(app.x_edit)+length(h)-1;  % 
                Ly2=pow2(nextpow2(Ly));    % Find smallest power of 2 that is > Ly
                X=fft(app.x_edit, Ly2);		   % Fast Fourier transform
                H=fft(h, Ly2);	           % Fast Fourier transform
                Y=X.*H;        	           % 
                app.x_edit1=real(ifft(Y, Ly2));      % Inverse fast Fourier transform
                app.x_edit1=app.x_edit1(1:1:Ly);               % Take just the first N elements
                app.x_edit1=app.x_edit1/max(abs(app.x_edit1));


            elseif strcmp(val_efe1, 'Off')
                app.x_edit1 = x;
                app.Ganancia1.BackgroundColor = 'red';
                
            end
            app.player = audioplayer(app.x_edit1,Fs);
            t2 = (0:(1/(length(app.x_edit1)-1)):1);
            plot(app.UIAxes, t2,app.x_edit1);                  
                
        end

        % Button pushed function: AplicarCambios_2
        function AplicarCambios_2Pushed(app, event)
          
        end

        % Value changed function: SwitchFiltro1
        function SwitchFiltro1ValueChanged(app, event)
            val_fil1 = app.SwitchFiltro1.Value;
            fil_sel = app.FiltroDropDown.Value;
            f_corte = app.Frecuenciadecorte1.Value;
            gan=app.Ganancia1.Value;
            
            [x,Fs] = audioread(app.archivo);
            
            if (strcmp(fil_sel, 'Pasabajas') && strcmp(val_fil1, 'On' ))
                    app.x_edit = gan*lowpass(x,f_corte,Fs);
                    app.Ganancia1.BackgroundColor = 'red';

            elseif (strcmp(fil_sel, 'Pasa-altas') && strcmp(val_fil1, 'On'))
                    app.x_edit = gan*highpass(x,f_corte,Fs);
                    app.Ganancia1.BackgroundColor = 'green';

            elseif (strcmp(fil_sel, 'Pasabandas')  &&  strcmp(val_fil1, 'On'))
                    app.x_edit = gan*lowpass(x,f_corte,Fs);
                    app.Ganancia1.BackgroundColor = 'blue';

            elseif (strcmp(fil_sel, 'Notch') && strcmp(val_fil1, 'On'))
                    app.x_edit = gan*lowpass(x,f_corte,Fs);
                    app.Ganancia1.BackgroundColor = 'blue';  
                    
            else
                app.x_edit = x;
                app.Ganancia1.BackgroundColor = 'red';
            end
            %app.player = audioplayer(app.x_edit,Fs);
            %t2 = (0:(1/(length(app.x_edit)-1)):1);
            %plot(app.UIAxes, t2,app.x_edit);        
        end

        % Value changed function: EfectoDropDown
        function EfectoDropDownValueChanged(app, event)
            val_fil1 = app.SwitchFiltro1.Value;
            
            [x,~] = audioread(app.archivo);

            if   strcmp(val_fil1, 'Off' )  
                app.x_edit=x;
            else
                app.x_edit=app.x_edit;
            end            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Cargar = uibutton(app.UIFigure, 'push');
            app.Cargar.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.Cargar.Icon = 'upload.png';
            app.Cargar.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.AudioMixerLabel = uilabel(app.UIFigure);
            app.AudioMixerLabel.Text = 'Audio Mixer';
            app.Play = uibutton(app.UIFigure, 'push');
            app.Play.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.Play.Icon = 'play.png';
            app.Play.Text = '';
            app.Pause = uibutton(app.UIFigure, 'push');
            app.Pause.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.Pause.Icon = 'pause.png';
            app.Pause.Text = '';
            app.Resume = uibutton(app.UIFigure, 'push');
            app.Resume.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.Resume.Icon = 'resume.png';
            app.Resume.Text = '';
            app.Stop = uibutton(app.UIFigure, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.Stop.Icon = 'stop.png';
            app.Stop.Text = '';
            app.FiltroDropDownLabel = uilabel(app.UIFigure);
            app.FiltroDropDownLabel.Text = 'Filtro';
            app.FiltroDropDown = uidropdown(app.UIFigure);
            app.FiltroDropDown.Items = {'Pasa-altas', 'Pasabajas', 'Pasabandas', 'Notch'};
            app.FiltroDropDown.Value = 'Pasa-altas';
            app.FiltroDropDown_2Label = uilabel(app.UIFigure);
            app.FiltroDropDown_2Label.Text = 'Filtro';
            app.FiltroDropDown_2 = uidropdown(app.UIFigure);
            app.FiltroDropDown_2.Items = {'Pasa-altas', 'Pasabajas', 'Pasabandas', 'Notch'};
            app.FiltroDropDown_2.Value = 'Pasa-altas';
            app.Canal1Label = uilabel(app.UIFigure);
            app.Canal1Label.Text = 'Canal 1';
            app.Canal2Label = uilabel(app.UIFigure);
            app.Canal2Label.Text = 'Canal 2';
            app.EfectoDropDownLabel = uilabel(app.UIFigure);
            app.EfectoDropDownLabel.Text = 'Efecto';
            app.EfectoDropDown = uidropdown(app.UIFigure);
            app.EfectoDropDown.Items = {'Eco', 'Vibrato', 'Distorsión', 'Reverberación', 'Flanger'};
            app.EfectoDropDown.ValueChangedFcn = createCallbackFcn(app, @EfectoDropDownValueChanged, true);
            app.EfectoDropDown.Value = 'Eco';
            app.EfectoDropDown_2Label = uilabel(app.UIFigure);
            app.EfectoDropDown_2Label.Text = 'Efecto';
            app.EfectoDropDown_2 = uidropdown(app.UIFigure);
            app.EfectoDropDown_2.Items = {'Eco', 'Vibrato', 'Distorsión', 'Reverberación', 'Flanger'};
            app.EfectoDropDown_2.Value = 'Eco';
            app.SwitchEfecto1 = uiswitch(app.UIFigure, 'slider');
            app.SwitchEfecto2 = uiswitch(app.UIFigure, 'slider');
            app.GananciaSpinnerLabel = uilabel(app.UIFigure);
            app.GananciaSpinnerLabel.Text = 'Ganancia';
            app.Ganancia1 = uispinner(app.UIFigure);
            app.Ganancia1.Step = 0.5;
            app.Ganancia1.Value = 1;
            app.NuevoarchivoEditFieldLabel = uilabel(app.UIFigure);
            app.NuevoarchivoEditFieldLabel.Text = 'Nuevo archivo';
            app.NuevoarchivoEditField = uieditfield(app.UIFigure, 'text');
            app.GenerarButton = uibutton(app.UIFigure, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Respuesta de los filtros')
            xlabel(app.UIAxes, 'Frecuencia (Hz)')
            ylabel(app.UIAxes, 'Amplitud')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Señal de Entrada')
            xlabel(app.UIAxes_2, 'Frecuencia (Hz)')
            ylabel(app.UIAxes_2, 'Amplitud')
            app.UIAxes_2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Señal de Salida')
            xlabel(app.UIAxes_3, 'Frecuencia (Hz)')
            ylabel(app.UIAxes_3, 'Amplitud')
            app.UIAxes_3.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.GenerarButton_2 = uibutton(app.UIFigure, 'push');
            app.GenerarButton_2.Text = 'Generar';
            app.GuardarVariablesEditFieldLabel = uilabel(app.UIFigure);
            app.GuardarVariablesEditFieldLabel.Text = 'Guardar Variables';
            app.GuardarVariablesEditField = uieditfield(app.UIFigure, 'text');
            app.matLabel = uilabel(app.UIFigure);
            app.matLabel.Text = '.mat';
            app.wavLabel = uilabel(app.UIFigure);
            app.wavLabel.Text = '.wav';
            app.GenerarSealdelFiltroButton = uibutton(app.UIFigure, 'push');
            app.GenerarSealdelFiltroButton.Text = 'Generar Señal del Filtro';
            app.GenerarSealdeEntradaButton = uibutton(app.UIFigure, 'push');
            app.GenerarSealdeEntradaButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarSealdeEntradaButtonPushed, true);
            app.GenerarSealdeEntradaButton.Text = 'Generar Señal de Entrada';
            app.GenerarSealdeSalidaButton = uibutton(app.UIFigure, 'push');
            app.GenerarSealdeSalidaButton.Text = 'Generar Señal de Salida';
            app.AplicarCambios_1 = uibutton(app.UIFigure, 'push');
            app.AplicarCambios_1.ButtonPushedFcn = createCallbackFcn(app, @AplicarCambios_1Pushed, true);
            app.AplicarCambios_1.Text = 'Aplicar Cambios';
            app.AplicarCambios_2 = uibutton(app.UIFigure, 'push');
            app.AplicarCambios_2.ButtonPushedFcn = createCallbackFcn(app, @AplicarCambios_2Pushed, true);
            app.AplicarCambios_2.Text = 'Aplicar Cambios';
            app.SwitchFiltro1 = uiswitch(app.UIFigure, 'slider');
            app.SwitchFiltro1.ValueChangedFcn = createCallbackFcn(app, @SwitchFiltro1ValueChanged, true);
            app.SwitchFiltro2 = uiswitch(app.UIFigure, 'slider');
            app.FrecuenciadecorteSpinnerLabel = uilabel(app.UIFigure);
            app.FrecuenciadecorteSpinnerLabel.Text = 'Frecuencia de corte';
            app.Frecuenciadecorte1 = uispinner(app.UIFigure);
            app.Frecuenciadecorte1.Step = 0.5;
            app.Frecuenciadecorte1.Value = 150;
            app.GananciaSpinner_2Label = uilabel(app.UIFigure);
            app.GananciaSpinner_2Label.Text = 'Ganancia';
            app.Ganancia2 = uispinner(app.UIFigure);
            app.Ganancia2.Step = 0.5;
            app.Ganancia2.Value = 1;
            app.FrecuenciadecorteSpinner_2Label = uilabel(app.UIFigure);
            app.FrecuenciadecorteSpinner_2Label.Text = 'Frecuencia de corte';
            app.Frecuenciadecorte2 = uispinner(app.UIFigure);
            app.Frecuenciadecorte2.Step = 0.5;
            app.Frecuenciadecorte2.Value = 150;
            app.AlfaLabel = uilabel(app.UIFigure);
            app.AlfaLabel.Text = 'Alfa';
            app.Alfa = uispinner(app.UIFigure);
            app.Alfa.Step = 0.5;
            app.Alfa.Value = 1;
            app.DelaySliderLabel = uilabel(app.UIFigure);
            app.DelaySliderLabel.Text = 'Delay';
            app.Delay = uislider(app.UIFigure);
            app.Delay.Limits = [0 11025];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end